classdef e2021practice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RidesPanel             matlab.ui.container.Panel
        bigDropButton          matlab.ui.control.Button
        tubesButton            matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        FoodPanel              matlab.ui.container.Panel
        pizzaButton            matlab.ui.control.Button
        pretzelButton          matlab.ui.control.Button
        Label_3                matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        TicketsRemainingPanel  matlab.ui.container.Panel
        Label_5                matlab.ui.control.Label
        resetButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        bigDrop = 0;
        tubes = 0;
        pizza = 0;
        pretzel = 0;
        ticketsTotal = 500;
        ticketsUsed = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label_5.Text = num2str(app.ticketsTotal - app.ticketsUsed);
        end

        % Button pushed function: bigDropButton
        function bigDropButtonPushed(app, event)
            app.bigDrop = app.bigDrop + 40;
            app.ticketsUsed = app.ticketsUsed + 40;
            if app.ticketsUsed < 500
                app.Label.Text = num2str(app.bigDrop);
                app.Label_5.Text = num2str(app.ticketsTotal - app.ticketsUsed);
            end
        end

        % Button pushed function: tubesButton
        function tubesButtonPushed(app, event)
            app.tubes = app.tubes + 15;
            app.ticketsUsed = app.ticketsUsed + 15;
            if app.ticketsUsed < 500
                app.Label_2.Text = num2str(app.tubes);
                app.Label_5.Text = num2str(app.ticketsTotal - app.ticketsUsed);
            end
        end

        % Button pushed function: pizzaButton
        function pizzaButtonPushed(app, event)
            app.pizza = app.pizza + 100;
            app.ticketsUsed = app.ticketsUsed + 100;
            if app.ticketsUsed < 500
                app.Label_3.Text = num2str(app.pizza);
                app.Label_5.Text = num2str(app.ticketsTotal - app.ticketsUsed);
            end
        end

        % Button pushed function: pretzelButton
        function pretzelButtonPushed(app, event)
            app.pretzel = app.pretzel + 20;
            app.ticketsUsed = app.ticketsUsed + 20;
            if app.ticketsUsed < 500
                app.Label_4.Text = num2str(app.pretzel);
                app.Label_5.Text = num2str(app.ticketsTotal - app.ticketsUsed);
            end
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.bigDrop = 0;
            app.tubes = 0;
            app.pizza = 0;
            app.pretzel = 0;
            app.ticketsTotal = 500;
            app.ticketsUsed = 0;
            app.Label.Text = num2str(app.tubes);
            app.Label_2.Text = num2str(app.tubes);
            app.Label_3.Text = num2str(app.pizza);
            app.Label_4.Text = num2str(app.pretzel);
            app.Label_5.Text = num2str(app.ticketsTotal - app.ticketsUsed);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RidesPanel = uipanel(app.UIFigure);
            app.RidesPanel.Title = 'Rides';
            app.bigDropButton = uibutton(app.RidesPanel, 'push');
            app.bigDropButton.ButtonPushedFcn = createCallbackFcn(app, @bigDropButtonPushed, true);
            app.bigDropButton.Text = 'bigDrop';
            app.tubesButton = uibutton(app.RidesPanel, 'push');
            app.tubesButton.ButtonPushedFcn = createCallbackFcn(app, @tubesButtonPushed, true);
            app.tubesButton.Text = 'tubes';
            app.Label = uilabel(app.RidesPanel);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.RidesPanel);
            app.Label_2.Text = '0';
            app.FoodPanel = uipanel(app.UIFigure);
            app.FoodPanel.Title = 'Food';
            app.pizzaButton = uibutton(app.FoodPanel, 'push');
            app.pizzaButton.ButtonPushedFcn = createCallbackFcn(app, @pizzaButtonPushed, true);
            app.pizzaButton.Text = 'pizza';
            app.pretzelButton = uibutton(app.FoodPanel, 'push');
            app.pretzelButton.ButtonPushedFcn = createCallbackFcn(app, @pretzelButtonPushed, true);
            app.pretzelButton.Text = 'pretzel';
            app.Label_3 = uilabel(app.FoodPanel);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.FoodPanel);
            app.Label_4.Text = '0';
            app.TicketsRemainingPanel = uipanel(app.UIFigure);
            app.TicketsRemainingPanel.Title = 'Tickets Remaining';
            app.Label_5 = uilabel(app.TicketsRemainingPanel);
            app.Label_5.Text = '0';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = e2021practice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end