classdef exam2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HogwartsHouseCupLeaderboardUIFigure  matlab.ui.Figure
        GryffindorPanel       matlab.ui.container.Panel
        gPic                  matlab.ui.control.Image
        gLabel                matlab.ui.control.Label
        SlytherinPanel        matlab.ui.container.Panel
        sPic                  matlab.ui.control.Image
        sLabel                matlab.ui.control.Label
        HufflepuffPanel       matlab.ui.container.Panel
        hPic                  matlab.ui.control.Image
        hLabel                matlab.ui.control.Label
        RavenclawPanel        matlab.ui.container.Panel
        rPic                  matlab.ui.control.Image
        rLabel                matlab.ui.control.Label
        PointsEditFieldLabel  matlab.ui.control.Label
        PointsEditField       matlab.ui.control.NumericEditField
        GainButton            matlab.ui.control.Button
        LoseButton            matlab.ui.control.Button
        houseSelec            matlab.ui.container.ButtonGroup
        GriffindorButton      matlab.ui.control.RadioButton
        SlytherinButton       matlab.ui.control.RadioButton
        HufflepuffButton      matlab.ui.control.RadioButton
        RavenclawButton       matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        gPoint = 0;
        sPoint = 0;
        hPoint = 0;
        rPoint = 0;
    end
    
    methods (Access = private)
        
        function  blit(app, gPoint, sPoint, hPoint, rPoint)
            app.gLabel.Text = num2str(gPoint);
            app.sLabel.Text = num2str(sPoint);
            app.hLabel.Text = num2str(hPoint);
            app.rLabel.Text = num2str(rPoint);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set all labels to 0
            blit(app, app.gPoint, app.sPoint, app.hPoint, app.rPoint);

        end

        % Button pushed function: GainButton
        function GainButtonPushed(app, event)
            if app.RavenclawButton.Value == 1
                app.rPoint = app.rPoint + app.PointsEditField.Value;            
            end
            
            if app.HufflepuffButton.Value == 1
                app.hPoint = app.hPoint + app.PointsEditField.Value;            
            end
            
            if app.SlytherinButton.Value == 1
                app.sPoint = app.sPoint + app.PointsEditField.Value;            
            end
            
            if app.GriffindorButton.Value == 1
                app.gPoint = app.gPoint + app.PointsEditField.Value;            
            end            
            
            blit(app, app.gPoint, app.sPoint, app.hPoint, app.rPoint);
        end

        % Button pushed function: LoseButton
        function LoseButtonPushed(app, event)
            if app.RavenclawButton.Value == 1
                app.rPoint = app.rPoint - app.PointsEditField.Value;
                if app.rPoint < 0
                    app.rPoint = 0;
                end
            end
            
            if app.HufflepuffButton.Value == 1
                app.hPoint = app.hPoint - app.PointsEditField.Value;  
                if app.hPoint < 0
                    app.hPoint = 0;
                end                
            end
            
            if app.SlytherinButton.Value == 1
                app.sPoint = app.sPoint - app.PointsEditField.Value;  
                if app.sPoint < 0
                    app.sPoint = 0;
                end                
            end
            
            if app.GriffindorButton.Value == 1
                app.gPoint = app.gPoint - app.PointsEditField.Value; 
                if app.gPoint < 0
                    app.gPoint = 0;
                end                
            end 
            
            blit(app, app.gPoint, app.sPoint, app.hPoint, app.rPoint);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HogwartsHouseCupLeaderboardUIFigure = uifigure('Visible', 'off');
            app.HogwartsHouseCupLeaderboardUIFigure.Name = '                                                                              Hogwarts House Cup Leaderboard';
            app.GryffindorPanel = uipanel(app.HogwartsHouseCupLeaderboardUIFigure);
            app.GryffindorPanel.Title = 'Gryffindor';
            app.gPic = uiimage(app.GryffindorPanel);
            app.gPic.ImageSource = 'gryffindor-crest.png';
            app.gLabel = uilabel(app.GryffindorPanel);
            app.gLabel.Text = '0';
            app.SlytherinPanel = uipanel(app.HogwartsHouseCupLeaderboardUIFigure);
            app.SlytherinPanel.Title = 'Slytherin';
            app.sPic = uiimage(app.SlytherinPanel);
            app.sPic.ImageSource = 'slytherin-crests.png';
            app.sLabel = uilabel(app.SlytherinPanel);
            app.sLabel.Text = '0';
            app.HufflepuffPanel = uipanel(app.HogwartsHouseCupLeaderboardUIFigure);
            app.HufflepuffPanel.Title = 'Hufflepuff';
            app.hPic = uiimage(app.HufflepuffPanel);
            app.hPic.ImageSource = 'hufflepuff-crest.png';
            app.hLabel = uilabel(app.HufflepuffPanel);
            app.hLabel.Text = '0';
            app.RavenclawPanel = uipanel(app.HogwartsHouseCupLeaderboardUIFigure);
            app.RavenclawPanel.Title = 'Ravenclaw';
            app.rPic = uiimage(app.RavenclawPanel);
            app.rPic.ImageSource = 'ravenclaw-crest.png';
            app.rLabel = uilabel(app.RavenclawPanel);
            app.rLabel.Text = '0';
            app.PointsEditFieldLabel = uilabel(app.HogwartsHouseCupLeaderboardUIFigure);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.HogwartsHouseCupLeaderboardUIFigure, 'numeric');
            app.GainButton = uibutton(app.HogwartsHouseCupLeaderboardUIFigure, 'push');
            app.GainButton.ButtonPushedFcn = createCallbackFcn(app, @GainButtonPushed, true);
            app.GainButton.Text = 'Gain';
            app.LoseButton = uibutton(app.HogwartsHouseCupLeaderboardUIFigure, 'push');
            app.LoseButton.ButtonPushedFcn = createCallbackFcn(app, @LoseButtonPushed, true);
            app.LoseButton.Text = 'Lose';
            app.houseSelec = uibuttongroup(app.HogwartsHouseCupLeaderboardUIFigure);
            app.houseSelec.Title = 'Select a House';
            app.GriffindorButton = uiradiobutton(app.houseSelec);
            app.GriffindorButton.Text = 'Griffindor';
            app.GriffindorButton.Value = true;
            app.SlytherinButton = uiradiobutton(app.houseSelec);
            app.SlytherinButton.Text = 'Slytherin';
            app.HufflepuffButton = uiradiobutton(app.houseSelec);
            app.HufflepuffButton.Text = 'Hufflepuff';
            app.RavenclawButton = uiradiobutton(app.houseSelec);
            app.RavenclawButton.Text = 'Ravenclaw';
            % Show the figure after all components are created
            app.HogwartsHouseCupLeaderboardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exam2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HogwartsHouseCupLeaderboardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HogwartsHouseCupLeaderboardUIFigure)
        end
    end
end