classdef communicationMATLAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        communicationUIFigure     matlab.ui.Figure
        LEDButtonGroup            matlab.ui.container.ButtonGroup
        OFFButton                 matlab.ui.control.ToggleButton
        ONButton                  matlab.ui.control.ToggleButton
        ButtonCounterLabel        matlab.ui.control.Label
        countLabel                matlab.ui.control.Label
        servoSliderLabel          matlab.ui.control.Label
        servoSlider               matlab.ui.control.Slider
        servoValueEditFieldLabel  matlab.ui.control.Label
        servoValueEditField       matlab.ui.control.NumericEditField
        sendValueButton           matlab.ui.control.Button
        Image                     matlab.ui.control.Image
    end

    
    properties (Access = private)
        serialObj;
        counter = 0;
    end
    
    methods (Access = private)
        
        function sendServoValue(app, degrees)
            %assume degrees is number (maybe int or float)
            degrees = round(degrees);
            command = sprintf("SERVO %d", degrees);
            
            fprintf("sending %s\n", command);
            writeline(app.serialObj, command);
            
        end
        
        function serialObjCallback(app, serialObj, eventData)
            message = strtrim(readline(serialObj)) %unsupressed to print
            
            if strcmpi(message, "B")
                app.counter = app.counter + 1;
                app.countLabel.Text = sprintf("Count = %d", app.counter);
            end
            
            %parameters
            messageArray = split(message);
            if strcmpi(messageArray(1), "X")
                xVal = str2double(messageArray(2));
                app.Image.Position(1) = xVal/2;
            end
            if strcmpi(messageArray(1), "Y")
                yVal = str2double(messageArray(2));
                app.Image.Position(2) = yVal/2;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.serialObj = serialport("COM3", 9600);
            flush(app.serialObj);
            configureCallback(app.serialObj, "terminator", @app.serialObjCallback);
            

        end

        % Selection changed function: LEDButtonGroup
        function LEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.LEDButtonGroup.SelectedObject;
            
            if selectedButton == app.OFFButton
               writeline(app.serialObj, "LED OFF")
            end
            
            if selectedButton == app.ONButton
                writeline(app.serialObj, "LED ON")
            end
        end

        % Value changed function: servoSlider
        function servoSliderValueChanged(app, event)
            value = app.servoSlider.Value;
            app.sendServoValue(value);
            
        end

        % Value changing function: servoSlider
        function servoSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sendServoValue(changingValue);
            
        end

        % Button pushed function: sendValueButton
        function sendValueButtonPushed(app, event)
            value = app.servoValueEditField.Value;
            app.sendServoValue(value);
            
            app.servoSlider.Value = value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.communicationUIFigure = uifigure('Visible', 'off');
            app.communicationUIFigure.Name = 'communication';
            app.LEDButtonGroup = uibuttongroup(app.communicationUIFigure);
            app.LEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LEDButtonGroupSelectionChanged, true);
            app.LEDButtonGroup.Title = 'LED';
            app.OFFButton = uitogglebutton(app.LEDButtonGroup);
            app.OFFButton.Text = 'OFF';
            app.OFFButton.Value = true;
            app.ONButton = uitogglebutton(app.LEDButtonGroup);
            app.ONButton.Text = 'ON';
            app.ButtonCounterLabel = uilabel(app.communicationUIFigure);
            app.ButtonCounterLabel.Text = 'Button Counter:';
            app.countLabel = uilabel(app.communicationUIFigure);
            app.countLabel.Text = 'Count = 0';
            app.servoSliderLabel = uilabel(app.communicationUIFigure);
            app.servoSliderLabel.Text = 'servoSlider';
            app.servoSlider = uislider(app.communicationUIFigure);
            app.servoSlider.Limits = [0 180];
            app.servoSlider.ValueChangedFcn = createCallbackFcn(app, @servoSliderValueChanged, true);
            app.servoSlider.ValueChangingFcn = createCallbackFcn(app, @servoSliderValueChanging, true);
            app.servoValueEditFieldLabel = uilabel(app.communicationUIFigure);
            app.servoValueEditFieldLabel.Text = 'servoValue';
            app.servoValueEditField = uieditfield(app.communicationUIFigure, 'numeric');
            app.sendValueButton = uibutton(app.communicationUIFigure, 'push');
            app.sendValueButton.ButtonPushedFcn = createCallbackFcn(app, @sendValueButtonPushed, true);
            app.sendValueButton.Text = 'sendValue';
            app.Image = uiimage(app.communicationUIFigure);
            app.Image.ImageSource = 'ship.png';
            % Show the figure after all components are created
            app.communicationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = communicationMATLAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.communicationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.communicationUIFigure)
        end
    end
end