classdef helloButton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        hullobuttonUIFigure  matlab.ui.Figure
        resetbuttonButton    matlab.ui.control.Button
        incrementButton      matlab.ui.control.Button
        decrementButton      matlab.ui.control.Button
        countLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        counter = 0;
        serialObj;
    end
    
    methods (Access = private)
        
        function updateView(app)
            fprintf("counter = %d\n", app.counter);
            app.countLabel.Text = sprintf("count = %d", app.counter);
            
        end
        
        function serialObjCallback(app, serialObj, ~)
            %fprintf("hit the callback\n")
            
            message = strtrim(readline(serialObj)) %unsupresssed allows message print
            
            if strcmpi(message,"B")
                app.counter = app.counter + 1;
                app.updateView();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.serialObj = serialport("COM3", 9600);
            flush(app.serialObj);
            configureCallback(app.serialObj, "terminator", @app.serialObjCallback);
            
        end

        % Button pushed function: resetbuttonButton
        function resetbuttonButtonPushed(app, event)
            fprintf("you pressed reset button\n");
            %app.resetbuttonButton.BackgroundColor = 'y';
            %app.hullobuttonUIFigure.Color = 'r';
            %app.resetbuttonButton.Text = 'Ben';
            
            %update counter
            app.counter = 0;
            %update display 
            app.updateView;
        end

        % Button pushed function: decrementButton
        function decrementButtonPushed(app, event)
            %update counter
            app.counter = app.counter - 1;
            %update display 
            app.updateView;
            writeline(app.serialObj, "LED OFF");
        end

        % Button pushed function: incrementButton
        function incrementButtonPushed(app, event)
            %update counter
            app.counter = app.counter + 1;
            %update display 
            app.updateView
            writeline(app.serialObj, "LED ON");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.hullobuttonUIFigure = uifigure('Visible', 'off');
            app.hullobuttonUIFigure.Name = 'hullo button';
            app.resetbuttonButton = uibutton(app.hullobuttonUIFigure, 'push');
            app.resetbuttonButton.ButtonPushedFcn = createCallbackFcn(app, @resetbuttonButtonPushed, true);
            app.resetbuttonButton.Text = 'reset button';
            app.incrementButton = uibutton(app.hullobuttonUIFigure, 'push');
            app.incrementButton.ButtonPushedFcn = createCallbackFcn(app, @incrementButtonPushed, true);
            app.incrementButton.Text = 'increment';
            app.decrementButton = uibutton(app.hullobuttonUIFigure, 'push');
            app.decrementButton.ButtonPushedFcn = createCallbackFcn(app, @decrementButtonPushed, true);
            app.decrementButton.Text = 'decrement';
            app.countLabel = uilabel(app.hullobuttonUIFigure);
            app.countLabel.Text = 'count = 0';
            % Show the figure after all components are created
            app.hullobuttonUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = helloButton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.hullobuttonUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.hullobuttonUIFigure)
        end
    end
end